classdef Simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ROBOTARMLabel  matlab.ui.control.Label
        OFFLabel       matlab.ui.control.Label
        OFFLamp        matlab.ui.control.Lamp
        STARTButton    matlab.ui.control.Button
        STOPButton     matlab.ui.control.Button
        INVERSEButton  matlab.ui.control.Button
        RESETButton    matlab.ui.control.Button
        PzLabel        matlab.ui.control.Label
        PyLabel        matlab.ui.control.Label
        PxLabel        matlab.ui.control.Label
        Label_7        matlab.ui.control.Label
        Label_6        matlab.ui.control.Label
        Label_5        matlab.ui.control.Label
        Label_4        matlab.ui.control.Label
        Label_3        matlab.ui.control.Label
        EditField_9    matlab.ui.control.NumericEditField
        EditField_8    matlab.ui.control.NumericEditField
        EditField_7    matlab.ui.control.NumericEditField
        EditField_5    matlab.ui.control.NumericEditField
        EditField_4    matlab.ui.control.NumericEditField
        EditField_3    matlab.ui.control.NumericEditField
        EditField_2    matlab.ui.control.NumericEditField
        EditField      matlab.ui.control.NumericEditField
        Slider_5       matlab.ui.control.Slider
        Slider_4       matlab.ui.control.Slider
        Slider_3       matlab.ui.control.Slider
        Slider_2       matlab.ui.control.Slider
        Slider         matlab.ui.control.Slider
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
    end

    methods (Access = private)

        function T_final = forwardKinematics(~, theta)

            theta = deg2rad(theta);

            L(1) = Link('d', 97,  'a', 14,   'alpha', pi/2);
            L(2) = Link('d', 0,   'a', 120,  'alpha', 0);
            L(3) = Link('d', 0,   'a', 0,    'alpha', pi/2);
            L(4) = Link('d', 120, 'a', 0,    'alpha', -pi/2);
            L(5) = Link('d', 0,   'a', 100,  'alpha', 0);

            robot = SerialLink(L, 'name', 'CustomRobot');

            q = [theta(1), theta(2)+deg2rad(90), theta(3), theta(4), theta(5)+deg2rad(180)];
            T_final = robot.fkine(q).T;
        end

        
        function theta_sol = inverseKinematics(~, px, py, pz)

            L(1) = Link('d', 97, 'a', 14,     'alpha', pi/2); 
            L(2) = Link('d', 0, 'a', 120,   'alpha', 0, 'offset', 90 * pi/180); 
            L(3) = Link('d', 0,   'a', 0,     'alpha', pi/2); 
            L(4) = Link('d', 120, 'a', 0,    'alpha', -pi/2); 
            L(5) = Link('d', 0,   'a', 100,     'alpha', 0, 'offset', 180 * pi/180);   

            robot = SerialLink(L, 'name', '5DOF_Robot');

            T_desired = transl(px, py, pz) * trotz(0); 
            q = robot.ikine(T_desired, 'mask', [1 1 1 0 0 0]); 
            theta_sol = round(rad2deg(q));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Slider
        function slider1(app, event)
            t1 = event.Value;  
            t1 = round(t1); 
            app.EditField.Value = t1; 

            ModelName = 'Robot';
            set_param([ModelName '/SG1'], 'Gain', num2str(t1));

            t1 = app.EditField.Value;
            t2 = app.EditField_2.Value;
            t3 = app.EditField_3.Value;
            t4 = app.EditField_4.Value;
            t5 = app.EditField_5.Value;
            theta = [t1; t2; t3; t4; t5];  

            T = forwardKinematics(app, theta);

            x_pos = round(T(1,4),1);
            y_pos = round(T(2,4),1);
            z_pos = round(T(3,4),1);
            app.EditField_7.Value = x_pos;
            app.EditField_8.Value = y_pos;
            app.EditField_9.Value = z_pos;
        end

        % Value changing function: Slider_2
        function slider2(app, event)
            t2 = event.Value;  
            t2 = round(t2); 
            app.EditField_2.Value = t2; 

            ModelName = 'Robot';
            set_param([ModelName '/SG2'], 'Gain', num2str(t2));  

            t1 = app.EditField.Value;
            t2 = app.EditField_2.Value;
            t3 = app.EditField_3.Value;
            t4 = app.EditField_4.Value;
            t5 = app.EditField_5.Value;
            theta = [t1; t2; t3; t4; t5];  

            T = forwardKinematics(app, theta);

            x_pos = round(T(1,4),1);
            y_pos = round(T(2,4),1);
            z_pos = round(T(3,4),1);
            app.EditField_7.Value = x_pos;
            app.EditField_8.Value = y_pos;
            app.EditField_9.Value = z_pos;
        end

        % Value changing function: Slider_3
        function slider3(app, event)
            t3 = event.Value; 
            t3 = round(t3);  
            app.EditField_3.Value = t3; 

            ModelName = 'Robot';
            set_param([ModelName '/SG3'], 'Gain', num2str(t3));

            t1 = app.EditField.Value;
            t2 = app.EditField_2.Value;
            t3 = app.EditField_3.Value;
            t4 = app.EditField_4.Value;
            t5 = app.EditField_5.Value;
            theta = [t1; t2; t3; t4; t5];  

            T = forwardKinematics(app, theta);

            x_pos = round(T(1,4),1);
            y_pos = round(T(2,4),1);
            z_pos = round(T(3,4),1);
            app.EditField_7.Value = x_pos;
            app.EditField_8.Value = y_pos;
            app.EditField_9.Value = z_pos;
        end

        % Value changing function: Slider_4
        function slider4(app, event)
            t4 = event.Value; 
            t4 = round(t4); 
            app.EditField_4.Value = t4;  

            ModelName = 'Robot';
            set_param([ModelName '/SG4'], 'Gain', num2str(t4)); 

            t1 = app.EditField.Value;
            t2 = app.EditField_2.Value;
            t3 = app.EditField_3.Value;
            t4 = app.EditField_4.Value;
            t5 = app.EditField_5.Value;
            theta = [t1; t2; t3; t4; t5];  

            T = forwardKinematics(app, theta);

            x_pos = round(T(1,4),1);
            y_pos = round(T(2,4),1);
            z_pos = round(T(3,4),1);
            app.EditField_7.Value = x_pos;
            app.EditField_8.Value = y_pos;
            app.EditField_9.Value = z_pos;
        end

        % Value changing function: Slider_5
        function slider5(app, event)
            t5 = event.Value; 
            t5 = round(t5); 
            app.EditField_5.Value = t5;  
           
            ModelName = 'Robot';
            set_param([ModelName '/SG5'], 'Gain', num2str(t5));   

            t1 = app.EditField.Value;
            t2 = app.EditField_2.Value;
            t3 = app.EditField_3.Value;
            t4 = app.EditField_4.Value;
            t5 = app.EditField_5.Value;
            theta = [t1; t2; t3; t4; t5];  

            T = forwardKinematics(app, theta);

            x_pos = round(T(1,4),1);
            y_pos = round(T(2,4),1);
            z_pos = round(T(3,4),1);
            app.EditField_7.Value = x_pos;
            app.EditField_8.Value = y_pos;
            app.EditField_9.Value = z_pos;
        end

        % Value changed function: EditField
        function edit1(app, event)
            t1 = app.EditField.Value;
            if isnan(t1)
                return;
            end
            t1 = round(t1);
            app.Slider.Value = t1;

            ModelName = 'Robot';
            set_param([ModelName '/SG1'], 'Gain', num2str(t1)); 

            t1 = app.EditField.Value;
            t2 = app.EditField_2.Value;
            t3 = app.EditField_3.Value;
            t4 = app.EditField_4.Value;
            t5 = app.EditField_5.Value;
            theta = [t1; t2; t3; t4; t5];  

            T = forwardKinematics(app, theta);

            x_pos = round(T(1,4),1);
            y_pos = round(T(2,4),1);
            z_pos = round(T(3,4),1);
            app.EditField_7.Value = x_pos;
            app.EditField_8.Value = y_pos;
            app.EditField_9.Value = z_pos;
        end

        % Value changed function: EditField_2
        function edit2(app, event)
            t2 = app.EditField_2.Value;
            if isnan(t2)
                return;
            end
            t2 = round(t2);
            app.Slider_2.Value = t2;

            ModelName = 'Robot';
            set_param([ModelName '/SG2'], 'Gain', num2str(t2)); 

            t1 = app.EditField.Value;
            t2 = app.EditField_2.Value;
            t3 = app.EditField_3.Value;
            t4 = app.EditField_4.Value;
            t5 = app.EditField_5.Value;
            theta = [t1; t2; t3; t4; t5];  

            T = forwardKinematics(app, theta);

            x_pos = round(T(1,4),1);
            y_pos = round(T(2,4),1);
            z_pos = round(T(3,4),1);
            app.EditField_7.Value = x_pos;
            app.EditField_8.Value = y_pos;
            app.EditField_9.Value = z_pos;
        end

        % Value changed function: EditField_3
        function edit3(app, event)
            t3 = app.EditField_3.Value;
            if isnan(t3)
                return;
            end
            t3 = round(t3);
            app.Slider_3.Value = t3;

            ModelName = 'Robot';
            set_param([ModelName '/SG3'], 'Gain', num2str(t3)); 

            t1 = app.EditField.Value;
            t2 = app.EditField_2.Value;
            t3 = app.EditField_3.Value;
            t4 = app.EditField_4.Value;
            t5 = app.EditField_5.Value;
            theta = [t1; t2; t3; t4; t5];  

            T = forwardKinematics(app, theta);

            x_pos = round(T(1,4),1);
            y_pos = round(T(2,4),1);
            z_pos = round(T(3,4),1);
            app.EditField_7.Value = x_pos;
            app.EditField_8.Value = y_pos;
            app.EditField_9.Value = z_pos;
        end

        % Value changed function: EditField_4
        function edit4(app, event)
            t4 = app.EditField_4.Value;
            if isnan(t4)
                return;
            end
            t4 = round(t4);
            app.Slider_4.Value = t4;

            ModelName = 'Robot';
            set_param([ModelName '/SG4'], 'Gain', num2str(t4)); 

            t1 = app.EditField.Value;
            t2 = app.EditField_2.Value;
            t3 = app.EditField_3.Value;
            t4 = app.EditField_4.Value;
            t5 = app.EditField_5.Value;
            theta = [t1; t2; t3; t4; t5];  

            T = forwardKinematics(app, theta);

            x_pos = round(T(1,4),1);
            y_pos = round(T(2,4),1);
            z_pos = round(T(3,4),1);
            app.EditField_7.Value = x_pos;
            app.EditField_8.Value = y_pos;
            app.EditField_9.Value = z_pos;
        end

        % Value changed function: EditField_5
        function edit5(app, event)
            t5 = app.EditField_5.Value;
            if isnan(t5)
                return;
            end
            t5 = round(t5);
            app.Slider_5.Value = t5;

            ModelName = 'Robot';
            set_param([ModelName '/SG5'], 'Gain', num2str(t5)); 

            t1 = app.EditField.Value;
            t2 = app.EditField_2.Value;
            t3 = app.EditField_3.Value;
            t4 = app.EditField_4.Value;
            t5 = app.EditField_5.Value;
            theta = [t1; t2; t3; t4; t5];  

            T = forwardKinematics(app, theta);

            x_pos = round(T(1,4),1);
            y_pos = round(T(2,4),1);
            z_pos = round(T(3,4),1);
            app.EditField_7.Value = x_pos;
            app.EditField_8.Value = y_pos;
            app.EditField_9.Value = z_pos;
        end

        % Button pushed function: STARTButton
        function start(app, event)
            ModelName = 'Robot';
            if ~bdIsLoaded(ModelName)
                load_system(ModelName);
            end
            simStatus = get_param(ModelName, 'SimulationStatus');

            if strcmp(simStatus, 'stopped')
                set_param(ModelName, 'SimulationCommand', 'start');
                app.OFFLamp.Color = 'green';
                app.OFFLabel.Text = 'ON';
            end
        end

        % Button pushed function: STOPButton
        function stop(app, event)
            ModelName = 'Robot'; 
            simStatus = get_param(ModelName, 'SimulationStatus');

            if ~strcmp(simStatus, 'stopped')
                set_param(ModelName, 'SimulationCommand', 'stop');
                app.OFFLamp.Color = 'red';
                app.OFFLabel.Text = 'OFF';
            end  
        end

        % Button pushed function: RESETButton
        function reset(app, event)
            app.Slider.Value = 0;
            app.Slider_2.Value = 0;
            app.Slider_3.Value = 0;
            app.Slider_4.Value = 0;
            app.Slider_5.Value = 0;

            app.EditField.Value = 0;
            app.EditField_2.Value = 0;
            app.EditField_3.Value = 0;
            app.EditField_4.Value = 0;
            app.EditField_5.Value = 0;

            ModelName = 'Robot';
            set_param([ModelName '/SG1'], 'Gain', '0');
            set_param([ModelName '/SG2'], 'Gain', '0');
            set_param([ModelName '/SG3'], 'Gain', '0');
            set_param([ModelName '/SG4'], 'Gain', '0');
            set_param([ModelName '/SG5'], 'Gain', '0');

            theta = [0; 0; 0; 0; 0];
            
            T = forwardKinematics(app, theta);

            x_pos = round(T(1,4),1);
            y_pos = round(T(2,4),1);
            z_pos = round(T(3,4),1);
            app.EditField_7.Value = x_pos;
            app.EditField_8.Value = y_pos;
            app.EditField_9.Value = z_pos;
        end

        % Button pushed function: INVERSEButton
        function inversekin(app, event)
            px    = app.EditField_7.Value;
            py    = app.EditField_8.Value;
            pz    = app.EditField_9.Value;

            if isnan(px) || isnan(py) || isnan(pz)
                return; 
            end

            theta_sol = inverseKinematics(app, px, py, pz); 

            app.Slider.Value = theta_sol(1);
            app.Slider_2.Value = theta_sol(2);
            app.Slider_3.Value = theta_sol(3);
            app.Slider_4.Value = theta_sol(4);
            app.Slider_5.Value = theta_sol(5);

            app.EditField.Value = theta_sol(1);
            app.EditField_2.Value = theta_sol(2);
            app.EditField_3.Value = theta_sol(3);
            app.EditField_4.Value = theta_sol(4);
            app.EditField_5.Value = theta_sol(5);

            ModelName = 'Robot';
            set_param([ModelName '/SG1'], 'Gain', num2str(theta_sol(1)));
            set_param([ModelName '/SG2'], 'Gain', num2str(theta_sol(2)));
            set_param([ModelName '/SG3'], 'Gain', num2str(theta_sol(3)));
            set_param([ModelName '/SG4'], 'Gain', num2str(theta_sol(4)));
            set_param([ModelName '/SG5'], 'Gain', num2str(theta_sol(5)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-90 90];
            app.Slider.MajorTicks = [-90 0 90];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @slider1, true);
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-90 90];
            app.Slider_2.MajorTicks = [-90 0 90];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @slider2, true);
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [-42 90];
            app.Slider_3.MajorTicks = [-42 0 90];
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @slider3, true);
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [-90 90];
            app.Slider_4.MajorTicks = [-90 0 90];
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @slider4, true);
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [0 180];
            app.Slider_5.MajorTicks = [0 90 180];
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @slider5, true);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @edit1, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @edit2, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @edit3, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @edit4, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @edit5, true);
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'θ1';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'θ2';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'θ3';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'θ4';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'θ5';
            app.PxLabel = uilabel(app.UIFigure);
            app.PxLabel.Text = 'Px';
            app.PyLabel = uilabel(app.UIFigure);
            app.PyLabel.Text = 'Py';
            app.PzLabel = uilabel(app.UIFigure);
            app.PzLabel.Text = 'Pz';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.RESETButton.Text = 'RESET';
            app.INVERSEButton = uibutton(app.UIFigure, 'push');
            app.INVERSEButton.ButtonPushedFcn = createCallbackFcn(app, @inversekin, true);
            app.INVERSEButton.Text = 'INVERSE';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @stop, true);
            app.STOPButton.Text = 'STOP';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.STARTButton.Text = 'START';
            app.OFFLamp = uilamp(app.UIFigure);
            app.OFFLabel = uilabel(app.UIFigure);
            app.OFFLabel.Text = 'OFF';
            app.ROBOTARMLabel = uilabel(app.UIFigure);
            app.ROBOTARMLabel.Text = 'ROBOT ARM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end